classdef Schmidt_factor_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SchmidtfactorcalculatorUIFigure  matlab.ui.Figure
        FilesMenu                 matlab.ui.container.Menu
        OpenOrientationFileMenu   matlab.ui.container.Menu
        ExportMenu                matlab.ui.container.Menu
        AllSFMenu                 matlab.ui.container.Menu
        MaxSFMenu                 matlab.ui.container.Menu
        SFfrequencyMenu           matlab.ui.container.Menu
        QueryMenu                 matlab.ui.container.Menu
        DeleteallMenu             matlab.ui.container.Menu
        CalculatesettingMenu      matlab.ui.container.Menu
        ComputingmethodMenu       matlab.ui.container.Menu
        GSFMenu                   matlab.ui.container.Menu
        ModifiedGSFMenu           matlab.ui.container.Menu
        CPMenu                    matlab.ui.container.Menu
        ConvertmethodMenu         matlab.ui.container.Menu
        OIMMenu                   matlab.ui.container.Menu
        CHANNEL5Menu              matlab.ui.container.Menu
        MaterialMenu              matlab.ui.container.Menu
        TiMenu                    matlab.ui.container.Menu
        ZrMenu                    matlab.ui.container.Menu
        MgMenu                    matlab.ui.container.Menu
        OthersMenu                matlab.ui.container.Menu
        AngleunitMenu             matlab.ui.container.Menu
        radMenu                   matlab.ui.container.Menu
        degreeMenu                matlab.ui.container.Menu
        DeformationsystemsselectionMenu  matlab.ui.container.Menu
        BasalslipMenu             matlab.ui.container.Menu
        PrismaticslipMenu         matlab.ui.container.Menu
        PyramidalslipaMenu        matlab.ui.container.Menu
        Pyramidalslipac1Menu      matlab.ui.container.Menu
        Pyramidalslipac2Menu      matlab.ui.container.Menu
        Tensiontwinning1Menu      matlab.ui.container.Menu
        Tensiontwinning2Menu      matlab.ui.container.Menu
        Compressiontwinning1Menu  matlab.ui.container.Menu
        Compressiontwinning2Menu  matlab.ui.container.Menu
        FilesettingMenu           matlab.ui.container.Menu
        AssigncolumnMenu          matlab.ui.container.Menu
        XYMenu                    matlab.ui.container.Menu
        EulerAnglesMenu           matlab.ui.container.Menu
        ChangedirectoryMenu       matlab.ui.container.Menu
        ExportdataMenu            matlab.ui.container.Menu
        StressMenu                matlab.ui.container.Menu
        UniaxialtensionxMenu      matlab.ui.container.Menu
        UniaxialcompressionxMenu  matlab.ui.container.Menu
        propotionaltensionMenu    matlab.ui.container.Menu
        CustomMenu                matlab.ui.container.Menu
        OthersMenu_2              matlab.ui.container.Menu
        HCPdemoMenu               matlab.ui.container.Menu
        WindowsizeMenu            matlab.ui.container.Menu
        LargeMenu                 matlab.ui.container.Menu
        MiddleMenu                matlab.ui.container.Menu
        SmallMenu                 matlab.ui.container.Menu
        SliptraceMenu             matlab.ui.container.Menu
        IPFlegendMenu             matlab.ui.container.Menu
        PFscatterMenu             matlab.ui.container.Menu
        IPFscatterMenu            matlab.ui.container.Menu
        OpenMenu                  matlab.ui.container.Menu
        CoordinaterealtionMenu    matlab.ui.container.Menu
        Menu2                     matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout5               matlab.ui.container.GridLayout
        Tree                      matlab.ui.container.Tree
        GridLayout6               matlab.ui.container.GridLayout
        GBButton                  matlab.ui.control.Button
        FreezeButton              matlab.ui.control.StateButton
        DeleteButton              matlab.ui.control.Button
        QueryButton               matlab.ui.control.StateButton
        TabGroup                  matlab.ui.container.TabGroup
        InfoTab                   matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        TextArea                  matlab.ui.control.TextArea
        StressTab                 matlab.ui.container.Tab
        GridLayout8               matlab.ui.container.GridLayout
        UITable                   matlab.ui.control.Table
        QueryTab                  matlab.ui.container.Tab
        GridLayout9               matlab.ui.container.GridLayout
        UITable2                  matlab.ui.control.Table
        RightPanel                matlab.ui.container.Panel
        GridLayout7               matlab.ui.container.GridLayout
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
        OldPath
        OpeDefDir = cd
        ConMet = 'OIM'
        AxiRat = 1.587 % Ti
        AxiRatNam = 'Ti'
        CryTyp = 'HCP'
        AngUni = 'rad'
        ColLis = [4,5,1:3]
        StrSta = [1,0,0;
                  0,0,0;
                  0,0,0]
        StrStaNam = 'Uniaxial tension (x)';
        CalMet = 'GSF'
        CalLis = [1,2,3,4,5,6,7,8,9]
        CRSS = ones(1,9);
        
        test = [] % store all instances
        GpuFla = 1
        Num = 0
        ExpDatDefDir = 'C:\SchmidtFactorCalculator\ExportedData\'
        
        GBLimit = [5 100]
        
        
        PreTab = []
        PloNo
        CatNo
        PloTyp
        
        TemSca
        TemLab
        
        HCPPlotFig
        HCPPlotAxe
        HCPPlotFlag = 0
        SliTraFig
        SliTraAxe
        SliTraBar
        SliTraTable
        SliTraLines
        SliTraText
        SliTraFreSca
        SliTraFreFlag = 0
        SliTraFlag = 0
        IPFlegendFig
        IPFlegendAxe
        PFscatFlag = 0
        PFscatFig
        PFscatAxe
        PFscatUic
        PFscatOpt = [1,0,0,1]; 
        PFscatInd = [0,0,0,1;
                     0,0,0,1;
                     0,0,0,1];
        IPFscatFlag = 0
        IPFscatFig
        IPFscatAxe
        IPFscatUic
        SurfaceNormalVectorCalculator = [];
        CoordinaterealtionFig
        CoordinaterealtionMatrix = eye(3);
        GBFig
        
        
        WinSiz = 'Middle'
        
        CurAxePos = []
        CurPanPos = []
        
        Colors
        
        Temp
        
        UIAxesColorbar
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OldPath = path;
            temp1 = mfilename('fullpath') ; temp2 = strfind(temp1,'\');
            ROOTPATH = temp1(1:temp2(end));
            cd(ROOTPATH);
            addpath('app_fun','cal_fun',genpath('SurfaceNormalVectorCalculator'));
            if ~isfolder('C:\SchmidtFactorCalculator\')
                dir = cd;
                cd("C:\");
                mkdir SchmidtFactorCalculator;
                mkdir ../SchmidtFactorCalculator ExportedData;
                cd(dir);
            else
                dir = cd;
                cd("C:\SchmidtFactorCalculator");
                Def = load('default.mat');
                cd(dir);
                app.OpeDefDir = Def.OpenDefaultDirectory;
                app.ConMet = Def.ConvertMethod;
                app.AxiRat = Def.AxialRatio;
                app.CryTyp = Def.CrystalType;
                app.AxiRatNam = Def.AxialRatioName;
                app.AngUni = Def.AngleUnit;
                app.ColLis = Def.ColumnList;
                app.StrSta = Def.StressState;
                app.StrStaNam = Def.StressStateName;
                app.CalMet = Def.CalculateMethod;
                app.CalLis = Def.CalculateList;
                app.ExpDatDefDir = Def.ExportedDataDefaultDirectory;
                app.WinSiz = Def.WindowSize;
                app.CRSS = Def.crss;
                app.PFscatOpt = Def.PFscatterOptions;
                app.PFscatInd = Def.PFscatterIndexs;
            end
            StartUpCheck(app);
            StressTableSet(app);
            app.UIAxes.Visible = 'off';
            app.UIAxes.NextPlot = 'add';
            app.UIAxes.XAxis.Visible = 'off';
            app.UIAxes.YAxis.Visible = 'off';
            app.SchmidtfactorcalculatorUIFigure.Resize = 'off';
            % axtoolbar(app.UIAxes,{'export','brush'});
            app.UIAxes.Interactions = [];
            switch app.WinSiz
                case 'Small'; siz = [600,450];
                case 'Middle'; siz = [800,640];
                case 'Large'; siz = [1200,960];
            end
            app.SchmidtfactorcalculatorUIFigure.Position(3:4) = siz;
            app.SchmidtfactorcalculatorUIFigure.Name = ...
                'HCP Schmidt factor calculator';
            app.Tree.SelectionChangedFcn = {@TreeActions,app};
            app.AllSFMenu.MenuSelectedFcn = {@ExportAllSF,app};
            app.MaxSFMenu.MenuSelectedFcn = {@ExportMaxSF,app};
            app.QueryMenu.MenuSelectedFcn = {@ExportQuery,app};
            app.SFfrequencyMenu.MenuSelectedFcn = {@ExportSFFrequency,app};
            app.HCPdemoMenu.MenuSelectedFcn = {@HCPdemoMenuSelected,app};
            app.DeleteButton.ButtonPushedFcn = {@DeleteButtonPushedFcn,app};
            app.QueryButton.ValueChangedFcn = {@QueryButtonValueChangedFcn,app};
            app.SliptraceMenu.MenuSelectedFcn = {@SlipTraceMenuSelected,app};
            app.SchmidtfactorcalculatorUIFigure.WindowButtonMotionFcn ...
                ={@UIFigureButtonMotion,app};
            app.SchmidtfactorcalculatorUIFigure.KeyPressFcn ...
                ={@UIFigureKeyPress,app};
            app.FreezeButton.ValueChangedFcn = {@FreezeButtonPushedFcn,app};
            app.GBButton.ButtonPushedFcn = {@GBButtonPushedFcn,app};
            app.IPFlegendMenu.MenuSelectedFcn = {@IPFlegendMenuSelected,app};
            app.PFscatterMenu.MenuSelectedFcn = {@PFscatterMenuSelected,app};
            app.OpenMenu.MenuSelectedFcn = {@IPFscatterMenuSelected,app};
            app.CoordinaterealtionMenu.MenuSelectedFcn = ...
                {@CoordinaterealtionMenuSelected,app};
            
            app.Colors = ...
                [0	0	0.515625000000000;
                0	0	0.750000000000000;
                0	0	0.984375000000000;
                0	0.218750000000000	1;
                0	0.453125000000000	1;
                0	0.687500000000000	1;
                0	0.921875000000000	1;
                0.156250000000000	1	0.843750000000000;
                0.390625000000000	1	0.609375000000000;
                0.625000000000000	1	0.375000000000000;
                0.859375000000000	1	0.140625000000000;
                1	0.906250000000000	0;
                1	0.671875000000000	0;
                1	0.437500000000000	0;
                1	0.203125000000000	0;
                0.968750000000000	0	0;
                0.734375000000000	0	0;
                0.500000000000000	0	0];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SchmidtfactorcalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {634, 634};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: OpenOrientationFileMenu
        function OpenOrientationFileMenuSelected(app, event)
            dir = cd;
            try
            cd(app.OpeDefDir)
            catch
                
            end
            [file,path] = uigetfile({'*.txt';'*.xlsx'},'Open File'); 
            if ~file
                cd(dir)
                return
            end
            cd(dir)
            app.OpeDefDir = path;
            uiprogressdlg(app.SchmidtfactorcalculatorUIFigure, ...
                'Message','Loading ...', ...
                'Title','Open Orientation File', ...
                'Indeterminate','on');
            app.Num = app.Num +1;
            app.test = ...
                [app.test,SF_instance( path,file,app) ];
            app.PloNo = length(app.test);
            app.CatNo = 1;
            app.PloTyp = 3;
            app.Tree.SelectedNodes = app.test(end).IPFCategoryNodes(1);
            TreeActions(app,app.Tree,app);
        end

        % Close request function: SchmidtfactorcalculatorUIFigure
        function SchmidtfactorcalculatorUIFigureCloseRequest(app, event)
            a = uiconfirm(app.SchmidtfactorcalculatorUIFigure, ...
                'Close app ?', ...
                'Close');
            if strcmp(a,'取消') || strcmp(a,'Cancel')
                return
            end
            if ishandle(app.HCPPlotFig)
               delete(app.HCPPlotFig); 
            end
            if ishandle(app.SliTraFig)
               delete(app.SliTraFig); 
            end
            if ishandle(app.IPFlegendFig)
               delete(app.IPFlegendFig); 
            end
            if ishandle(app.PFscatFig)
               delete(app.PFscatFig); 
            end
            if ishandle(app.IPFscatFig)
               delete(app.IPFscatFig); 
            end
            if ishandle(app.SurfaceNormalVectorCalculator)
               delete(app.SurfaceNormalVectorCalculator); 
            end
            if ishandle(app.CoordinaterealtionFig)
               delete(app.CoordinaterealtionFig); 
            end
            
            dir = cd;
            cd("C:\SchmidtFactorCalculator");
            OpenDefaultDirectory = app.OpeDefDir;
            ConvertMethod = app.ConMet;
            AxialRatio = app.AxiRat;
            AxialRatioName = app.AxiRatNam;
            CrystalType = app.CryTyp;
            AngleUnit = app.AngUni;
            ColumnList = app.ColLis;
            StressState = app.StrSta;
            StressStateName = app.StrStaNam;
            CalculateMethod = app.CalMet;
            CalculateList = app.CalLis;
            ExportedDataDefaultDirectory = app.ExpDatDefDir;
            WindowSize = app.WinSiz;
            PFscatterOptions = app.PFscatOpt;
            PFscatterIndexs = app.PFscatInd;
            crss = app.CRSS;
            save('default.mat', ...
                'OpenDefaultDirectory','ConvertMethod', ...
                'AxialRatio','AngleUnit', ...
                'ColumnList','StressState', ...
                'CalculateMethod','CalculateList', ...
                'AxialRatioName','StressStateName', ...
                'ExportedDataDefaultDirectory', ...
                'WindowSize','crss','PFscatterOptions', ...
                'PFscatterIndexs','CrystalType')
            cd(dir);
            path(app.OldPath)
            delete(app)
        end

        % Menu selected function: GSFMenu
        function GSFMenuSelected(app, event)
            % Computing method - GSF
            if strcmp(app.GSFMenu.Checked ,'off')
                app.GSFMenu.Checked = 'on';
                app.ModifiedGSFMenu.Checked = 'off';
                app.CPMenu.Checked = 'off';
                app.CalMet = 'GSF';
            end
        end

        % Menu selected function: ModifiedGSFMenu
        function ModifiedGSFMenuSelected(app, event)
            % Computing method - Modified GSF
            if strcmp(app.ModifiedGSFMenu.Checked ,'off')
                app.GSFMenu.Checked = 'off';
                app.ModifiedGSFMenu.Checked = 'on';
                app.CPMenu.Checked = 'off';
                app.CalMet = 'Modified GSF';
            end
        end

        % Menu selected function: CPMenu
        function CPMenuSelected(app, event)
            % Computing method - CP
            if strcmp(app.CPMenu.Checked ,'off')
                app.GSFMenu.Checked = 'off';
                app.ModifiedGSFMenu.Checked = 'off';
                app.CPMenu.Checked = 'on';
                app.CalMet = 'CP';
            end
        end

        % Menu selected function: OIMMenu
        function OIMMenuSelected(app, event)
            % Convert method - OIM
            if strcmp(app.OIMMenu.Checked,'off')
                app.OIMMenu.Checked = 'on';
                app.CHANNEL5Menu.Checked = 'off';
                app.ConMet = 'OIM';
            end
        end

        % Menu selected function: CHANNEL5Menu
        function CHANNEL5MenuSelected(app, event)
            % Convert method - CHANNEL5
            if strcmp(app.CHANNEL5Menu.Checked,'off')
                app.OIMMenu.Checked = 'off';
                app.CHANNEL5Menu.Checked = 'on';
                app.ConMet = 'CHANNEL5';
            end
        end

        % Menu selected function: TiMenu
        function TiMenuSelected(app, event)
            % Axial Ratio - Ti
            if strcmp(app.TiMenu.Checked,'off')
                app.TiMenu.Checked = 'on';
                app.ZrMenu.Checked = 'off';
                app.MgMenu.Checked = 'off';
                app.OthersMenu.Checked = 'off';
                app.AxiRat = 1.587;
                app.AxiRatNam = 'Ti';
                app.CryTyp = 'HCP';
            end
        end

        % Menu selected function: ZrMenu
        function ZrMenuSelected(app, event)
            % Axial Ratio - Zr
            if strcmp(app.ZrMenu.Checked,'off')
                app.TiMenu.Checked = 'off';
                app.ZrMenu.Checked = 'on';
                app.MgMenu.Checked = 'off';
                app.OthersMenu.Checked = 'off';
                app.AxiRat = 1.593;
                app.AxiRatNam = 'Zr';
                app.CryTyp = 'HCP';
            end
        end

        % Menu selected function: MgMenu
        function MgMenuSelected(app, event)
            % Axial Ratio - Mg
            if strcmp(app.MgMenu.Checked,'off')
                app.TiMenu.Checked = 'off';
                app.ZrMenu.Checked = 'off';
                app.MgMenu.Checked = 'on';
                app.OthersMenu.Checked = 'off';
                app.AxiRat = 1.633;
                app.AxiRatNam = 'Mg';
                app.CryTyp = 'HCP';
            end
        end

        % Menu selected function: OthersMenu
        function OthersMenuSelected(app, event)
            % Axial Ratio - Others
            if strcmp(app.OthersMenu.Checked,'off')
                prompt = {'Axial Ratio (c/a):'};
                dlgtitle = 'Axial Ratio';
                definput = {num2str(app.AxiRat)};
                opts.Interpreter = 'tex';
                answer = inputdlg(prompt,dlgtitle,[1 40],definput,opts);
                if isempty(answer); return; end
                val = str2double(answer{1});
                if val<=1 || val>=2
                    uialert(app.SchmidtfactorcalculatorUIFigure,'Wrong value','error');
                    return
                end
                app.TiMenu.Checked = 'off';
                app.ZrMenu.Checked = 'off';
                app.MgMenu.Checked = 'off';
                app.OthersMenu.Checked = 'on';
                app.AxiRat = val;
            end
        end

        % Menu selected function: radMenu
        function radMenuSelected(app, event)
            % Angle Unit - rad
            if strcmp(app.radMenu.Checked,'off')
                app.radMenu.Checked = 'on';
                app.degreeMenu.Checked = 'off';
                app.AngUni = 'rad';
            end
        end

        % Menu selected function: degreeMenu
        function degreeMenuSelected(app, event)
            % Angle Unit - degree
            if strcmp(app.degreeMenu.Checked,'off')
                app.radMenu.Checked = 'off';
                app.degreeMenu.Checked = 'on';
                app.AngUni = 'degree';
            end
        end

        % Menu selected function: BasalslipMenu
        function BasalslipMenuSelected(app, event)
            % Basal slip Menu Selected
            if strcmp(app.BasalslipMenu.Checked, 'off')
                app.BasalslipMenu.Checked = 'on';
            else
                app.BasalslipMenu.Checked = 'off';
            end
            ObtainCalculateList(app)
        end

        % Menu selected function: PrismaticslipMenu
        function PrismaticslipMenuSelected(app, event)
            % Prismatic slip Menu Selected
            if strcmp(app.PrismaticslipMenu.Checked, 'off')
                app.PrismaticslipMenu.Checked = 'on';
            else
                app.PrismaticslipMenu.Checked = 'off';
            end
            ObtainCalculateList(app)
        end

        % Menu selected function: PyramidalslipaMenu
        function PyramidalslipaMenuSelected(app, event)
            % Pyramidal slip 1 Menu Selected
            if strcmp(app.PyramidalslipaMenu.Checked, 'off')
                app.PyramidalslipaMenu.Checked = 'on';
            else
                app.PyramidalslipaMenu.Checked = 'off';
            end
            ObtainCalculateList(app)
        end

        % Menu selected function: Pyramidalslipac1Menu
        function Pyramidalslipac1MenuSelected(app, event)
            % Pyramidal slip 2 Menu Selected
            if strcmp(app.Pyramidalslipac1Menu.Checked, 'off')
                app.Pyramidalslipac1Menu.Checked = 'on';
            else
                app.Pyramidalslipac1Menu.Checked = 'off';
            end
            ObtainCalculateList(app)
        end

        % Menu selected function: Pyramidalslipac2Menu
        function Pyramidalslipac2MenuSelected(app, event)
            % Pyramidal slip 3 Menu Selected
            if strcmp(app.Pyramidalslipac2Menu.Checked, 'off')
                app.Pyramidalslipac2Menu.Checked = 'on';
            else
                app.Pyramidalslipac2Menu.Checked = 'off';
            end
            ObtainCalculateList(app)
        end

        % Menu selected function: Tensiontwinning1Menu
        function Tensiontwinning1MenuSelected(app, event)
            % Tension twinning 1 Menu Selected
            if strcmp(app.Tensiontwinning1Menu.Checked, 'off')
                app.Tensiontwinning1Menu.Checked = 'on';
            else
                app.Tensiontwinning1Menu.Checked = 'off';
            end
            ObtainCalculateList(app)
        end

        % Menu selected function: Tensiontwinning2Menu
        function Tensiontwinning2MenuSelected(app, event)
            % Tension twinning 2 Menu Selected
            if strcmp(app.Tensiontwinning2Menu.Checked, 'off')
                app.Tensiontwinning2Menu.Checked = 'on';
            else
                app.Tensiontwinning2Menu.Checked = 'off';
            end
            ObtainCalculateList(app)
        end

        % Menu selected function: Compressiontwinning1Menu
        function Compressiontwinning1MenuSelected(app, event)
            % Compression twinning 1 Menu Selected
            if strcmp(app.Compressiontwinning1Menu.Checked, 'off')
                app.Compressiontwinning1Menu.Checked = 'on';
            else
                app.Compressiontwinning1Menu.Checked = 'off';
            end
            ObtainCalculateList(app)
        end

        % Menu selected function: Compressiontwinning2Menu
        function Compressiontwinning2MenuSelected(app, event)
            % Compression twinning 2 Menu Selected
            if strcmp(app.Compressiontwinning2Menu.Checked, 'off')
                app.Compressiontwinning2Menu.Checked = 'on';
            else
                app.Compressiontwinning2Menu.Checked = 'off';
            end
            ObtainCalculateList(app)
        end

        % Menu selected function: Menu2
        function Menu2Selected(app, event)
            a=1;
        end

        % Menu selected function: XYMenu
        function XYMenuSelected(app, event)
            % assign column - XY
            prompt = {'From :','To :'};
            dlgtitle = 'XY Column';
            definput = {num2str(app.ColLis(1)),num2str(app.ColLis(2))};
            opts.Interpreter = 'tex';
            answer = inputdlg(prompt,dlgtitle,[1 10],definput,opts);
            if isempty(answer); return; end
            val = [str2double(answer{1}),str2double(answer{2})];
            if val(1)<=0 || val(2)<=0 || ...
                    val(1)~=fix(val(1)) || val(2)~=fix(val(2))...
                    || val(1)>=val(2)
                uialert(app.UIFigure,'Wrong value','error');
                return
            end
            app.ColLis(1:2) = [val(1),val(2)];
        end

        % Menu selected function: EulerAnglesMenu
        function EulerAnglesMenuSelected(app, event)
            % assign column - Euler Angles
            prompt = {'From :','To :'};
            dlgtitle = 'Euler Angles Column';
            definput = {num2str(app.ColLis(3)),num2str(app.ColLis(5))};
            opts.Interpreter = 'tex';
            answer = inputdlg(prompt,dlgtitle,[1 10],definput,opts);
            if isempty(answer); return; end
            val = [str2double(answer{1}),str2double(answer{2})];
            if val(1)<=0 || val(2)<=0 || ...
                    val(1)~=fix(val(1)) || val(2)~=fix(val(2))...
                    || val(2)-val(1)~=2
                uialert(app.SchmidtfactorcalculatorUIFigure,'Wrong value','error');
                return
            end
            app.ColLis(3:5) = val(1):val(2);
        end

        % Menu selected function: UniaxialtensionxMenu
        function UniaxialtensionxMenuSelected(app, event)
            % Stress State - uniaxial tension (x)
            if strcmp(app.UniaxialtensionxMenu.Checked, 'off')
                app.UniaxialtensionxMenu.Checked = 'on';
                app.UniaxialcompressionxMenu.Checked = 'off';
                app.propotionaltensionMenu.Checked = 'off';
                app.CustomMenu.Checked = 'off';
                app.StrStaNam = 'Uniaxial tension (x)';
                app.StrSta = [1,0,0;
                              0,0,0;
                              0,0,0];
                RefreshUITable(app);
            end
        end

        % Menu selected function: UniaxialcompressionxMenu
        function UniaxialcompressionxMenuSelected(app, event)
            % Stress State - uniaxial compression (x)
            if strcmp(app.UniaxialcompressionxMenu.Checked, 'off')
                app.UniaxialtensionxMenu.Checked = 'off';
                app.UniaxialcompressionxMenu.Checked = 'on';
                app.propotionaltensionMenu.Checked = 'off';
                app.CustomMenu.Checked = 'off';
                app.StrStaNam = 'Uniaxial compression (x)';
                app.StrSta = [-1,0,0;
                              0,0,0;
                              0,0,0];
                RefreshUITable(app);
            end
        end

        % Menu selected function: propotionaltensionMenu
        function propotionaltensionMenuSelected(app, event)
            % Stress State - 1:1 propotional tension
            if strcmp(app.propotionaltensionMenu.Checked, 'off')
                app.UniaxialtensionxMenu.Checked = 'off';
                app.UniaxialcompressionxMenu.Checked = 'off';
                app.propotionaltensionMenu.Checked = 'on';
                app.CustomMenu.Checked = 'off';
                app.StrStaNam = '1:1 propotional tension';
                app.StrSta = [1,0,0;
                              0,1,0;
                              0,0,0];
                RefreshUITable(app);
            end
        end

        % Callback function
        function ScreenshotMenu_2Selected(app, event)
            % change screenshot output default directory
            path = uigetdir(app.ScrShoDefDir); 
            if ~path
                return
            end
            app.ScrShoDefDir = path;
        end

        % Menu selected function: ExportdataMenu
        function ExportdataMenuSelected(app, event)
            % change export data output default directory
            path = uigetdir(app.ExpDatDefDir); 
            if ~path
                return
            end
            app.ExpDatDefDir = path;
        end

        % Menu selected function: DeleteallMenu
        function DeleteallMenuSelected(app, event)
            % delete all data
            if isempty(app.test)
               uialert(app.SchmidtfactorcalculatorUIFigure,'No Data !','Empty'); 
               return
            end
            Ans = uiconfirm(app.SchmidtfactorcalculatorUIFigure, ...
                'OK to delete all data ?','Clear All','Icon','warning');
            if strcmp(Ans,'确定') || strcmp(Ans,'OK')
                fun = @(a) Delete(a,app);
                arrayfun(fun,app.test);
            end
        end

        % Callback function
        function QueryCancelButtonPushed(app, event)
            % Query Cancel Button
            app.QueryAddButton.Enable = 'off';
            app.QueryCancelButton.Enable = 'off';
            app.Tree.Enable = 'on';
            app.TabGroup.SelectedTab = app.PreTab;
            app.SchmidtfactorcalculatorUIFigure.WindowButtonDownFcn...
                ='';
            app.FilesMenu.Enable = 'on';
            app.CalculatesettingMenu.Enable = 'on';
            app.FilesettingMenu.Enable = 'on';
            app.StressMenu.Enable = 'on';
        end

        % Menu selected function: LargeMenu
        function LargeMenuSelected(app, event)
            if strcmp(app.LargeMenu.Checked, 'off')
                app.LargeMenu.Checked = 'on';
                app.MiddleMenu.Checked = 'off';
                app.SmallMenu.Checked = 'off';
                pos_o = app.SchmidtfactorcalculatorUIFigure.Position;
                pos_n = [1200,960];
                pos = [pos_o(1),pos_o(2)-pos_n(2)+pos_o(4), ...
                       pos_n];
                app.SchmidtfactorcalculatorUIFigure.Position = pos;
                app.WinSiz = 'Large';
            end
        end

        % Menu selected function: SmallMenu
        function SmallMenuSelected(app, event)
            if strcmp(app.SmallMenu.Checked, 'off')
                app.LargeMenu.Checked = 'off';
                app.MiddleMenu.Checked = 'off';
                app.SmallMenu.Checked = 'on';
                pos_o = app.SchmidtfactorcalculatorUIFigure.Position;
                pos_n = [600,450];
                pos = [pos_o(1),pos_o(2)-pos_n(2)+pos_o(4), ...
                       pos_n];
                app.SchmidtfactorcalculatorUIFigure.Position = pos;
                app.WinSiz = 'Small';
            end
        end

        % Menu selected function: MiddleMenu
        function MiddleMenuSelected(app, event)
            if strcmp(app.MiddleMenu.Checked, 'off')
                app.LargeMenu.Checked = 'off';
                app.MiddleMenu.Checked = 'on';
                app.SmallMenu.Checked = 'off';
                pos_o = app.SchmidtfactorcalculatorUIFigure.Position;
                pos_n = [800,640];
                pos = [pos_o(1),pos_o(2)-pos_n(2)+pos_o(4), ...
                       pos_n];
                app.SchmidtfactorcalculatorUIFigure.Position = pos;
                app.WinSiz = 'Middle';
            end
        end

        % Callback function
        function CRSSMenuSelected(app, event)
            % CRSS ratio
            prompt = {'basal slip:','prismatic slip:','pyramidal 1:','pyramidal 1:', ...
                'TT1:','TT2:','CT1:','CT2:'};
            dlgtitle = 'CRSS';
            dims = [1 35];
            definput = arrayfun(@(val) num2str(val),app.CRSS,'UniformOutput',false);
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(answer); return; end
            val = cellfun(@(a) str2double(a),answer);
            app.CRSS = val;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SchmidtfactorcalculatorUIFigure = uifigure('Visible', 'off');
            app.SchmidtfactorcalculatorUIFigure.Name = 'Schmidt factor calculator';
            app.SchmidtfactorcalculatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @SchmidtfactorcalculatorUIFigureCloseRequest, true);
            app.SchmidtfactorcalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FilesMenu = uimenu(app.SchmidtfactorcalculatorUIFigure);
            app.FilesMenu.Text = 'Files';
            app.OpenOrientationFileMenu = uimenu(app.FilesMenu);
            app.OpenOrientationFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenOrientationFileMenuSelected, true);
            app.OpenOrientationFileMenu.Text = 'Open Orientation File';
            app.ExportMenu = uimenu(app.FilesMenu);
            app.ExportMenu.Text = 'Export';
            app.AllSFMenu = uimenu(app.ExportMenu);
            app.AllSFMenu.Text = 'All SF';
            app.MaxSFMenu = uimenu(app.ExportMenu);
            app.MaxSFMenu.Text = 'Max SF';
            app.SFfrequencyMenu = uimenu(app.ExportMenu);
            app.SFfrequencyMenu.Text = 'SF frequency';
            app.QueryMenu = uimenu(app.ExportMenu);
            app.QueryMenu.Text = 'Query';
            app.DeleteallMenu = uimenu(app.FilesMenu);
            app.DeleteallMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteallMenuSelected, true);
            app.DeleteallMenu.Text = 'Delete all';
            app.CalculatesettingMenu = uimenu(app.SchmidtfactorcalculatorUIFigure);
            app.CalculatesettingMenu.Text = 'Calculate setting';
            app.ComputingmethodMenu = uimenu(app.CalculatesettingMenu);
            app.ComputingmethodMenu.Text = 'Computing method';
            app.GSFMenu = uimenu(app.ComputingmethodMenu);
            app.GSFMenu.MenuSelectedFcn = createCallbackFcn(app, @GSFMenuSelected, true);
            app.GSFMenu.Text = 'GSF';
            app.ModifiedGSFMenu = uimenu(app.ComputingmethodMenu);
            app.ModifiedGSFMenu.MenuSelectedFcn = createCallbackFcn(app, @ModifiedGSFMenuSelected, true);
            app.ModifiedGSFMenu.Text = 'Modified GSF';
            app.CPMenu = uimenu(app.ComputingmethodMenu);
            app.CPMenu.MenuSelectedFcn = createCallbackFcn(app, @CPMenuSelected, true);
            app.CPMenu.Text = 'CP';
            app.ConvertmethodMenu = uimenu(app.CalculatesettingMenu);
            app.ConvertmethodMenu.Text = 'Convert method';
            app.OIMMenu = uimenu(app.ConvertmethodMenu);
            app.OIMMenu.MenuSelectedFcn = createCallbackFcn(app, @OIMMenuSelected, true);
            app.OIMMenu.Text = 'OIM';
            app.CHANNEL5Menu = uimenu(app.ConvertmethodMenu);
            app.CHANNEL5Menu.MenuSelectedFcn = createCallbackFcn(app, @CHANNEL5MenuSelected, true);
            app.CHANNEL5Menu.Text = 'CHANNEL5';
            app.MaterialMenu = uimenu(app.CalculatesettingMenu);
            app.MaterialMenu.Text = 'Material';
            app.TiMenu = uimenu(app.MaterialMenu);
            app.TiMenu.MenuSelectedFcn = createCallbackFcn(app, @TiMenuSelected, true);
            app.TiMenu.Text = 'Ti';
            app.ZrMenu = uimenu(app.MaterialMenu);
            app.ZrMenu.MenuSelectedFcn = createCallbackFcn(app, @ZrMenuSelected, true);
            app.ZrMenu.Text = 'Zr';
            app.MgMenu = uimenu(app.MaterialMenu);
            app.MgMenu.MenuSelectedFcn = createCallbackFcn(app, @MgMenuSelected, true);
            app.MgMenu.Text = 'Mg';
            app.OthersMenu = uimenu(app.MaterialMenu);
            app.OthersMenu.MenuSelectedFcn = createCallbackFcn(app, @OthersMenuSelected, true);
            app.OthersMenu.Text = 'Others';
            app.AngleunitMenu = uimenu(app.CalculatesettingMenu);
            app.AngleunitMenu.Text = 'Angle unit';
            app.radMenu = uimenu(app.AngleunitMenu);
            app.radMenu.MenuSelectedFcn = createCallbackFcn(app, @radMenuSelected, true);
            app.radMenu.Text = 'rad';
            app.degreeMenu = uimenu(app.AngleunitMenu);
            app.degreeMenu.MenuSelectedFcn = createCallbackFcn(app, @degreeMenuSelected, true);
            app.degreeMenu.Text = 'degree';
            app.DeformationsystemsselectionMenu = uimenu(app.CalculatesettingMenu);
            app.DeformationsystemsselectionMenu.Text = 'Deformation systems selection';
            app.BasalslipMenu = uimenu(app.DeformationsystemsselectionMenu);
            app.BasalslipMenu.MenuSelectedFcn = createCallbackFcn(app, @BasalslipMenuSelected, true);
            app.BasalslipMenu.Text = 'Basal slip';
            app.PrismaticslipMenu = uimenu(app.DeformationsystemsselectionMenu);
            app.PrismaticslipMenu.MenuSelectedFcn = createCallbackFcn(app, @PrismaticslipMenuSelected, true);
            app.PrismaticslipMenu.Text = 'Prismatic slip';
            app.PyramidalslipaMenu = uimenu(app.DeformationsystemsselectionMenu);
            app.PyramidalslipaMenu.MenuSelectedFcn = createCallbackFcn(app, @PyramidalslipaMenuSelected, true);
            app.PyramidalslipaMenu.Text = 'Pyramidal slip <a>';
            app.Pyramidalslipac1Menu = uimenu(app.DeformationsystemsselectionMenu);
            app.Pyramidalslipac1Menu.MenuSelectedFcn = createCallbackFcn(app, @Pyramidalslipac1MenuSelected, true);
            app.Pyramidalslipac1Menu.Text = 'Pyramidal slip <a+c> -1';
            app.Pyramidalslipac2Menu = uimenu(app.DeformationsystemsselectionMenu);
            app.Pyramidalslipac2Menu.MenuSelectedFcn = createCallbackFcn(app, @Pyramidalslipac2MenuSelected, true);
            app.Pyramidalslipac2Menu.Text = 'Pyramidal slip <a+c> -2';
            app.Tensiontwinning1Menu = uimenu(app.DeformationsystemsselectionMenu);
            app.Tensiontwinning1Menu.MenuSelectedFcn = createCallbackFcn(app, @Tensiontwinning1MenuSelected, true);
            app.Tensiontwinning1Menu.Text = 'Tension twinning 1';
            app.Tensiontwinning2Menu = uimenu(app.DeformationsystemsselectionMenu);
            app.Tensiontwinning2Menu.MenuSelectedFcn = createCallbackFcn(app, @Tensiontwinning2MenuSelected, true);
            app.Tensiontwinning2Menu.Text = 'Tension twinning 2';
            app.Compressiontwinning1Menu = uimenu(app.DeformationsystemsselectionMenu);
            app.Compressiontwinning1Menu.MenuSelectedFcn = createCallbackFcn(app, @Compressiontwinning1MenuSelected, true);
            app.Compressiontwinning1Menu.Text = 'Compression twinning 1';
            app.Compressiontwinning2Menu = uimenu(app.DeformationsystemsselectionMenu);
            app.Compressiontwinning2Menu.MenuSelectedFcn = createCallbackFcn(app, @Compressiontwinning2MenuSelected, true);
            app.Compressiontwinning2Menu.Text = 'Compression twinning 2';
            app.FilesettingMenu = uimenu(app.SchmidtfactorcalculatorUIFigure);
            app.FilesettingMenu.Text = 'File setting';
            app.AssigncolumnMenu = uimenu(app.FilesettingMenu);
            app.AssigncolumnMenu.Text = 'Assign column';
            app.XYMenu = uimenu(app.AssigncolumnMenu);
            app.XYMenu.MenuSelectedFcn = createCallbackFcn(app, @XYMenuSelected, true);
            app.XYMenu.Text = 'XY';
            app.EulerAnglesMenu = uimenu(app.AssigncolumnMenu);
            app.EulerAnglesMenu.MenuSelectedFcn = createCallbackFcn(app, @EulerAnglesMenuSelected, true);
            app.EulerAnglesMenu.Text = 'Euler Angles';
            app.ChangedirectoryMenu = uimenu(app.FilesettingMenu);
            app.ChangedirectoryMenu.Text = 'Change directory';
            app.ExportdataMenu = uimenu(app.ChangedirectoryMenu);
            app.ExportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportdataMenuSelected, true);
            app.ExportdataMenu.Text = 'Export data';
            app.StressMenu = uimenu(app.SchmidtfactorcalculatorUIFigure);
            app.StressMenu.Text = 'Stress';
            app.UniaxialtensionxMenu = uimenu(app.StressMenu);
            app.UniaxialtensionxMenu.MenuSelectedFcn = createCallbackFcn(app, @UniaxialtensionxMenuSelected, true);
            app.UniaxialtensionxMenu.Text = 'Uniaxial tension (x)';
            app.UniaxialcompressionxMenu = uimenu(app.StressMenu);
            app.UniaxialcompressionxMenu.MenuSelectedFcn = createCallbackFcn(app, @UniaxialcompressionxMenuSelected, true);
            app.UniaxialcompressionxMenu.Text = 'Uniaxial compression (x)';
            app.propotionaltensionMenu = uimenu(app.StressMenu);
            app.propotionaltensionMenu.MenuSelectedFcn = createCallbackFcn(app, @propotionaltensionMenuSelected, true);
            app.propotionaltensionMenu.Text = '1:1 propotional tension';
            app.CustomMenu = uimenu(app.StressMenu);
            app.CustomMenu.Text = 'Custom';
            app.OthersMenu_2 = uimenu(app.SchmidtfactorcalculatorUIFigure);
            app.OthersMenu_2.Text = 'Others';
            app.HCPdemoMenu = uimenu(app.OthersMenu_2);
            app.HCPdemoMenu.Text = 'HCP demo ';
            app.WindowsizeMenu = uimenu(app.OthersMenu_2);
            app.WindowsizeMenu.Text = 'Window size';
            app.LargeMenu = uimenu(app.WindowsizeMenu);
            app.LargeMenu.MenuSelectedFcn = createCallbackFcn(app, @LargeMenuSelected, true);
            app.LargeMenu.Text = 'Large';
            app.MiddleMenu = uimenu(app.WindowsizeMenu);
            app.MiddleMenu.MenuSelectedFcn = createCallbackFcn(app, @MiddleMenuSelected, true);
            app.MiddleMenu.Text = 'Middle';
            app.SmallMenu = uimenu(app.WindowsizeMenu);
            app.SmallMenu.MenuSelectedFcn = createCallbackFcn(app, @SmallMenuSelected, true);
            app.SmallMenu.Text = 'Small';
            app.SliptraceMenu = uimenu(app.OthersMenu_2);
            app.SliptraceMenu.Text = 'Slip trace';
            app.IPFlegendMenu = uimenu(app.OthersMenu_2);
            app.IPFlegendMenu.Text = 'IPF legend';
            app.PFscatterMenu = uimenu(app.OthersMenu_2);
            app.PFscatterMenu.Text = 'PF scatter';
            app.IPFscatterMenu = uimenu(app.OthersMenu_2);
            app.IPFscatterMenu.Text = 'IPF scatter';
            app.OpenMenu = uimenu(app.IPFscatterMenu);
            app.OpenMenu.Text = 'Open';
            app.CoordinaterealtionMenu = uimenu(app.IPFscatterMenu);
            app.CoordinaterealtionMenu.Text = 'Coordinate realtion';
            app.Menu2 = uimenu(app.SchmidtfactorcalculatorUIFigure);
            app.Menu2.MenuSelectedFcn = createCallbackFcn(app, @Menu2Selected, true);
            app.Menu2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.SchmidtfactorcalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.GridLayout4 = uigridlayout(app.InfoTab);
            app.TextArea = uitextarea(app.GridLayout4);
            app.StressTab = uitab(app.TabGroup);
            app.StressTab.Title = 'Stress';
            app.GridLayout8 = uigridlayout(app.StressTab);
            app.UITable = uitable(app.GridLayout8);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.QueryTab = uitab(app.TabGroup);
            app.QueryTab.Title = 'Query';
            app.GridLayout9 = uigridlayout(app.QueryTab);
            app.UITable2 = uitable(app.GridLayout9);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.QueryButton = uibutton(app.GridLayout6, 'state');
            app.QueryButton.Enable = 'off';
            app.QueryButton.Text = 'Query';
            app.DeleteButton = uibutton(app.GridLayout6, 'push');
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            app.FreezeButton = uibutton(app.GridLayout6, 'state');
            app.FreezeButton.Enable = 'off';
            app.FreezeButton.Text = 'Freeze';
            app.GBButton = uibutton(app.GridLayout6, 'push');
            app.GBButton.Enable = 'off';
            app.GBButton.Text = 'GB';
            app.Tree = uitree(app.GridLayout5);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout7);
            % Show the figure after all components are created
            app.SchmidtfactorcalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Schmidt_factor_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SchmidtfactorcalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SchmidtfactorcalculatorUIFigure)
        end
    end
end